classdef app_prototype < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        SelectthemotorDropDown  matlab.ui.control.DropDown
        SelectthemotorLabel     matlab.ui.control.Label
        Gauge_vol               matlab.ui.control.Gauge
        GaugevoltageLabel       matlab.ui.control.Label
        Gauge_temp              matlab.ui.control.Gauge
        GaugetemperatureLabel   matlab.ui.control.Label
        Gauge_pos               matlab.ui.control.Gauge
        GaugepositionLabel      matlab.ui.control.Label
        StopButton              matlab.ui.control.Button
        StartButton             matlab.ui.control.Button
        UIAxes_vol              matlab.ui.control.UIAxes
        UIAxes_temp             matlab.ui.control.UIAxes
        UIAxes_pos              matlab.ui.control.UIAxes
    end


    properties (Access = private)
        % In this section, we define the properties of the application
        % class. You can access the values of these properties using
        % app.XXX.
        sub = [] % Subsriber object.
        time_array = cell(1000); % An array of monitoring time.
        idx = 0; % Current index.
        data_motor = zeros(1000, 3, 6); % Data matrix for motor 1.
    end

    methods (Access = private)
    % This section defines the methods of the class application.
       
    function func_receive_msg(app, ~, msg)
    % This is the callback function for the subscriber.
    % Will be executed whenever a msg is published.
    % The received msg will be automatically given to this func as the
    % input "msg".
            % Update the current index.
            app.idx = app.idx + 1;
            % Get current time
            tnow = datetime("now");
            % Save the data collection time.
            app.time_array{app.idx} = tnow;

            % Parse the data to get a matrix of monitoring data.
            tmp_data = parse_data(app, msg);
            % Save the CM data for EVERY motor.
            for i = 1:6
                motor_id = i;
                app.data_motor(app.idx, :, motor_id) = tmp_data(:, motor_id);
                saveAndPlotMotorData(app, motor_id);
            end
       end


       function saveAndPlotMotorData(app, motor_id)                   
            % Get tnow.
            tnow = app.time_array{app.idx};
            % Hanldes of the Figures.
            plt_handles = {app.UIAxes_pos, app.UIAxes_temp, app.UIAxes_vol};
            plt_jauges = {app.Gauge_pos, app.Gauge_temp, app.Gauge_vol};
            colors = {[0.2, 0.4, 0.8], [0.8, 0.2, 0.2], [1, 1, 0]};
            % Loop for position, temperature and voltage.
            for j = 1:3
                % Read the current measurement.
                y = app.data_motor(app.idx, j, motor_id);
                % Get the handle to the plot.
                plt = plt_handles{j};
                plt_j = plt_jauges{j};
                color = colors{j};

                % Get the current x-axis limits of the app's UIAxes_pos.
                xLL = plt.XLim(1);
                xUL = plt.XLim(2);
                
                % Check if the current time (tnow) is out of bounds.
                if (tnow > xUL)
                    % If it's out of bounds, add 15 seconds to the x-axis limits.
                    xtra = seconds(5);
                    plt.XLim = [xLL + xtra, tnow + xtra];
                    % Adjust the x-axis ticks to maintain readability.
                    plt.XTick = linspace(xLL + xtra, tnow + xtra, 5);
                end
                % Only plot the data from the selected motor (in the
                % dropdown menu).
                if motor_id == str2num(app.SelectthemotorDropDown.Value)

                    % Plot the position data at the current time tnow.
                    plot(plt, tnow, y, ".b", 'Color', color);
                    
                    % Adjust the y-axis limits to show variations better.
                    if app.idx <= 2
                        yLL = floor(max(y*.95, 0));
                        yUL = ceil(y*1.05);
                    else
                        y_lim = plt.YLim;
                        yLL = y_lim(1);
                        yUL = y_lim(2);
                        if y < yLL
                            yLL = floor(.95*y);
                        if y > yUL
                            yUL = ceil(1.05*y);
                        end
                        end
                    end
                    plt.YLim = [yLL, yUL];
                    plt.YTick = round(linspace(yLL, yUL, 5), 20, "significant");
                    
                    % Plot the current data in the jauge
                    plt_j.Limits = [yLL, yUL];
                    plt_j.Value = y;
                end
            end            
        end


       % This function parse the message to get a data matrix.
       % Format of the output: 3*6 matrix.
       % The rows: Position, Temperature, Voltage. The cols: Motors 1-6.
        function data = parse_data(~, msg)
            % Retrieve info from the message
            % Split the string into lines
            data_lines = strsplit(msg.Data, '\n');
            
            % Initialize arrays to store the data
            position_data = [];
            temperature_data = [];
            voltage_data = [];
            
            % Iterate through each line and extract the values
            for i = 1:length(data_lines)
                line = data_lines{i};
                % Split each line by ':' to separate the key and values
                parts = strsplit(line, ':');
                if length(parts) == 2
                    key = strtrim(parts{1});
                    values = str2num(strtrim(strrep(strrep(parts{2}, '[', ''), ']',''))); %# Remove '[' and convert to numeric array
            
                    % Check which key corresponds to the data and store it in the respective array
                    if strcmp(key, 'position')
                        position_data = values;
                    elseif strcmp(key, 'temperature')
                        temperature_data = values;
                    elseif strcmp(key, 'voltage')
                        voltage_data = values;
                    end
                end
            end
            
            data = [position_data; temperature_data; voltage_data];
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            plt_handles = {app.UIAxes_pos, app.UIAxes_temp, app.UIAxes_vol};

            for j = 1:3
                plt = plt_handles{j};
                % Plot invisible data point to initialize datetime ruler
                plot(plt, datetime("now","Format","hh:mm:ss"),50,"MarkerEdgeColor","none");           
                % Set x limits and ticks
                t = datetime("now","Format","hh:mm:ss") + seconds(1:60);
                xLL = t(1) - seconds(5);
                xUL = t(length(t));
                plt.XLim = [xLL xUL];
                plt.XAxis.TickLabelFormat = 'hh:mm:ss';
                plt.XTick = linspace(xLL,xUL,5);
                
                % Set y limits and ticks
                %plt.YLim = [0 500];
                %plt.YTick = round(linspace(0,500,5),2,"significant");
                grid(plt,"on");
                hold(plt,"on");
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
           % Shutdown ros nodes, if any.
           rosshutdown;               
           % Connect to the robot: The ip address is 192.168.0.103.
           % Set this ip address to be the fake robot's, if you are
           % using the offline testing code (here, 10.152.100.6)
           rosinit('192.168.0.103', 11311)
           % rosinit('192.168.1.14', 11311)
           % rosinit('138.195.233.201', 11311)

           % Create a subscriber to the 'condition_monitoring' topic
           my_callback = @(src, msg)func_receive_msg(app, src, msg);
           app.sub = rossubscriber('/condition_monitoring', my_callback);            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Close the ROS connection
            rosshutdown;
            for i = 1:6
                %% This section save the data and export to .csv file.
                motor_id = i;
                file_name = ['data_motor_' num2str(motor_id) '.csv'];
                % Extract a time array from the 'app' object from the 1st element to the 'idx' element.
                time = app.time_array(1:app.idx)';            
                % Extract data related to 'motor_1' from the 'app' object up to the 'idx' element.
                tmp_data = app.data_motor(1:app.idx, :, motor_id);            
                % Extract the 'position,' 'temperature,' and 'voltage' columns from 'tmp_data.'
                position = tmp_data(:, 1);
                temperature = tmp_data(:, 2);
                voltage = tmp_data(:, 3);            
                % Create a table from the extracted data columns: time, position, temperature, voltage.
                table_motor_i = table(time, position, temperature, voltage);            
                % Write the table to a CSV file named 'data_motor_1.csv'.
                writetable(table_motor_i, file_name);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Close the ROS connection
            rosshutdown;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Demo_Condition monitoring';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes_pos = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_pos, 'Time (Seconds)')
            ylabel(app.UIAxes_pos, 'position')
            app.UIAxes_temp = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_temp, 'Time (Seconds)')
            ylabel(app.UIAxes_temp, 'temperature')
            app.UIAxes_vol = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_vol, 'Time (Seconds)')
            ylabel(app.UIAxes_vol, 'voltage')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.GaugepositionLabel = uilabel(app.UIFigure);
            app.GaugepositionLabel.Text = 'Gauge (position)';
            app.Gauge_pos = uigauge(app.UIFigure, 'circular');
            app.GaugetemperatureLabel = uilabel(app.UIFigure);
            app.GaugetemperatureLabel.Text = 'Gauge (temperature)';
            app.Gauge_temp = uigauge(app.UIFigure, 'circular');
            app.GaugevoltageLabel = uilabel(app.UIFigure);
            app.GaugevoltageLabel.Text = 'Gauge (voltage)';
            app.Gauge_vol = uigauge(app.UIFigure, 'circular');
            app.SelectthemotorLabel = uilabel(app.UIFigure);
            app.SelectthemotorLabel.Text = 'Select the motor :';
            app.SelectthemotorDropDown = uidropdown(app.UIFigure);
            app.SelectthemotorDropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.SelectthemotorDropDown.Value = '1';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_prototype

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end