classdef data_vis_in_real_time < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        StopButton                 matlab.ui.control.Button
        StartButton                matlab.ui.control.Button
        Updatedevery05secondLabel  matlab.ui.control.Label
        inNatickMALabel            matlab.ui.control.Label
        UIAxes                     matlab.ui.control.UIAxes
    end


    properties (Access = private)
        CMTimer % Timer object
    end

    methods (Access = private)
    
        function CMTimerFcn(app, src, event)
            % Get current time
            tnow = datetime("now");
            
            % Get the condition-monitoring data from the robot
            y = get_data_from_robot(app);
            
            % Plot data and adjust XLim if current time is out of bounds
            xLL = app.UIAxes.XLim(1);
            xUL = app.UIAxes.XLim(2);
            if(tnow > xUL)
               xtra = seconds(15);
               app.UIAxes.XLim = [xLL+xtra tnow+xtra];
               app.UIAxes.XTick = linspace(xLL+xtra,tnow+xtra,5);
            end
            plot(app.UIAxes, tnow, y, ".b");
            
            % Adjust YLim to show variations better
            yLL = max(y-10, 0); 
            yUL = y + 10;
            app.UIAxes.YLim = [yLL yUL];
            app.UIAxes.YTick = round(linspace(yLL,yUL,5),2,"significant");
       end

       % Get the condition-monitoring data from the robot
        function data = get_data_from_robot(app)
            % Generate random number for testing
            % To be updated later
            try
                data = 45+5*rand();
            catch
                disp("Error getting data");
            end 
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Plot invisible data point to initialize datetime ruler
            plot(app.UIAxes, datetime("now","Format","hh:mm:ss"),50,"MarkerEdgeColor","none");
           
            % Set x limits and ticks
            t = datetime("now","Format","hh:mm:ss") + seconds(1:60);
            xLL = t(1) - seconds(5);
            xUL = t(length(t));
            app.UIAxes.XLim = [xLL xUL];
            app.UIAxes.XAxis.TickLabelFormat = 'hh:mm:ss';
            app.UIAxes.XTick = linspace(xLL,xUL,5);
            
            % Set y limits and ticks
            app.UIAxes.YLim = [0 50];
            app.UIAxes.YTick = round(linspace(0,50,5),2,"significant");
            grid(app.UIAxes,"on");
            hold(app.UIAxes,"on");
            
            % Create timer object
            app.CMTimer = timer(...
            "ExecutionMode", "fixedRate", ...      % Run timer repeatedly
            "Period", .5, ...                       % Period is 5 seconds
            "BusyMode", "queue",...                % Queue timer callbacks when busy
            "TimerFcn", @app.CMTimerFcn);   % Callback that runs every period
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            % If timer is not running, start it
            if strcmp(app.CMTimer.Running,"off")
               % Start timer
               start(app.CMTimer);
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Stop timer
            stop(app.CMTimer);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Stop timer, then delete timer and figure
            stop(app.CMTimer);
            delete(app.CMTimer);
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Demo_Condition monitoring';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time (Seconds)')
            ylabel(app.UIAxes, 'Condition-monitoring signals')
            app.inNatickMALabel = uilabel(app.UIFigure);
            app.inNatickMALabel.Text = 'This is a demo of how to visualize the condition-monitoring data in real-time';
            app.Updatedevery05secondLabel = uilabel(app.UIFigure);
            app.Updatedevery05secondLabel.Text = 'Updated every 0.5 second';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = data_vis_in_real_time

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end