classdef app_prototype < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure         matlab.ui.Figure
        StopButton       matlab.ui.control.Button
        StartButton      matlab.ui.control.Button
        inNatickMALabel  matlab.ui.control.Label
        UIAxes_vol       matlab.ui.control.UIAxes
        UIAxes_temp      matlab.ui.control.UIAxes
        UIAxes_pos       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        % In this section, we define the properties of the application
        % class. You can access the values of these properties using
        % app.XXX.
        sub = [] % Subsriber object.
        time_array = cell(1000); % An array of monitoring time.
        idx = 0; % Current index.
        data_motor_1 = zeros(1000, 3); % Data matrix for motor 1.
    end

    methods (Access = private)
    % This section defines the methods of the class application.
       
    function func_receive_msg(app, ~, msg)
       % This is the callback function for the subscriber.
       % Will be executed whenever a msg is published.
       % The received msg will be automatically given to this func as the
       % input "msg".
            % Update the current index.
            app.idx = app.idx + 1;
            % Get current time
            tnow = datetime("now");
            % Save the data collection time.
            app.time_array{app.idx} = tnow;

            % Parse the data to get a matrix of monitoring data.
            tmp_data = parse_data(app, msg);          
            % For motor 1:
            motor_id = 1;
            % Save the CM data.
            app.data_motor_1(app.idx, :) = tmp_data(:, motor_id);


            % Plot the position.
            y = tmp_data(1, 1);

            % Plot data and adjust XLim if current time is out of bounds
            xLL = app.UIAxes_pos.XLim(1);
            xUL = app.UIAxes_pos.XLim(2);
            if(tnow > xUL)
               xtra = seconds(15);
               app.UIAxes_pos.XLim = [xLL+xtra tnow+xtra];
               app.UIAxes_pos.XTick = linspace(xLL+xtra,tnow+xtra,5);
            end
            plot(app.UIAxes_pos, tnow, y, ".b");

            % Adjust YLim to show variations better
            yLL = max(y-100, 0); 
            yUL = y + 100;
            app.UIAxes_pos.YLim = [yLL yUL];
            app.UIAxes_pos.YTick = round(linspace(yLL,yUL,5),2,"significant");
       end


    %    function saveAndPlotMotorData(app, motor_id)       
    %         % Extract the position data (y) from the data_motor_1.
    %         y = app.data_motor_1(app.idx, motor_id);
    %         % Get tnow.
    %         tnow = app.time_array{app.idx};
    % 
    %         % Get the current x-axis limits of the app's UIAxes_pos.
    %         xLL = app.UIAxes_pos.XLim(1);
    %         xUL = app.UIAxes_pos.XLim(2);
    % 
    %         % Check if the current time (tnow) is out of bounds.
    %         if (tnow > xUL)
    %             % If it's out of bounds, add 15 seconds to the x-axis limits.
    %             xtra = seconds(15);
    %             app.UIAxes_pos.XLim = [xLL + xtra, tnow + xtra];
    %             % Adjust the x-axis ticks to maintain readability.
    %             app.UIAxes_pos.XTick = linspace(xLL + xtra, tnow + xtra, 5);
    %         end
    % 
    %         % Plot the position data at the current time tnow.
    %         plot(app.UIAxes_pos, tnow, y, ".b");
    % 
    %         % Adjust the y-axis limits to show variations better.
    %         yLL = max(y - 100, 0);
    %         yUL = y + 100;
    %         app.UIAxes_pos.YLim = [yLL, yUL];
    %         app.UIAxes_pos.YTick = round(linspace(yLL, yUL, 5), 2, "significant");
    % end


       % This function parse the message to get a data matrix.
       % Format of the output: 3*6 matrix.
       % The rows: Position, Temperature, Voltage. The cols: Motors 1-6.
        function data = parse_data(app, msg)
            % Retrieve info from the message
            % Split the string into lines
            data_lines = strsplit(msg.Data, '\n');
            
            % Initialize arrays to store the data
            position_data = [];
            temperature_data = [];
            voltage_data = [];
            
            % Iterate through each line and extract the values
            for i = 1:length(data_lines)
                line = data_lines{i};
                % Split each line by ':' to separate the key and values
                parts = strsplit(line, ':');
                if length(parts) == 2
                    key = strtrim(parts{1});
                    values = str2num(strtrim(strrep(strrep(parts{2}, '[', ''), ']',''))); %# Remove '[' and convert to numeric array
            
                    % Check which key corresponds to the data and store it in the respective array
                    if strcmp(key, 'position')
                        position_data = values;
                    elseif strcmp(key, 'temperature')
                        temperature_data = values;
                    elseif strcmp(key, 'voltage')
                        voltage_data = values;
                    end
                end
            end
            
            data = [position_data; temperature_data; voltage_data];
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Plot invisible data point to initialize datetime ruler
            plot(app.UIAxes_pos, datetime("now","Format","hh:mm:ss"),50,"MarkerEdgeColor","none");           
            % Set x limits and ticks
            t = datetime("now","Format","hh:mm:ss") + seconds(1:60);
            xLL = t(1) - seconds(5);
            xUL = t(length(t));
            app.UIAxes_pos.XLim = [xLL xUL];
            app.UIAxes_pos.XAxis.TickLabelFormat = 'hh:mm:ss';
            app.UIAxes_pos.XTick = linspace(xLL,xUL,5);
            
            % Set y limits and ticks
            %app.UIAxes_pos.YLim = [0 500];
            %app.UIAxes_pos.YTick = round(linspace(0,500,5),2,"significant");
            grid(app.UIAxes_pos,"on");
            hold(app.UIAxes_pos,"on");
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
           % Shutdown ros nodes, if any.
           rosshutdown;               
           % Connect to the robot: The ip address is 192.168.0.103.
           % Set this ip address to be the fake robot's, if you are
           % using the offline testing code.
           rosinit('192.168.1.14', 11311)

           % Create a subscriber to the 'condition_monitoring' topic
           my_callback = @(src, msg)func_receive_msg(app, src, msg);
           app.sub = rossubscriber('/condition_monitoring', my_callback);            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Close the ROS connection
            rosshutdown;
            
            %% This section save the data and export to .csv file.
            % Extract a time array from the 'app' object from the 1st element to the 'idx' element.
            time = app.time_array(1:app.idx)';            
            % Extract data related to 'motor_1' from the 'app' object up to the 'idx' element.
            tmp_data = app.data_motor_1(1:app.idx, :);            
            % Extract the 'position,' 'temperature,' and 'voltage' columns from 'tmp_data.'
            position = tmp_data(:, 1);
            temperature = tmp_data(:, 2);
            voltage = tmp_data(:, 3);            
            % Create a table from the extracted data columns: time, position, temperature, voltage.
            table_motor_1 = table(time, position, temperature, voltage);            
            % Write the table to a CSV file named 'data_motor_1.csv'.
            writetable(table_motor_1, 'data_motor_1.csv');
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Close the ROS connection
            rosshutdown;
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Demo_Condition monitoring';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes_pos = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_pos, 'Time (Seconds)')
            ylabel(app.UIAxes_pos, 'position')
            app.UIAxes_temp = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_temp, 'Time (Seconds)')
            ylabel(app.UIAxes_temp, 'temperature')
            app.UIAxes_vol = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_vol, 'Time (Seconds)')
            ylabel(app.UIAxes_vol, 'voltage')
            app.inNatickMALabel = uilabel(app.UIFigure);
            app.inNatickMALabel.Text = 'Motor 1';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_prototype

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end