classdef app_prototype_fault_detection_master < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FailureinjectionSwitch          matlab.ui.control.Switch
        FailureinjectionSwitchLabel     matlab.ui.control.Label
        InjectfailureonDropDown         matlab.ui.control.DropDown
        InjectfailureonDropDownLabel    matlab.ui.control.Label
        ConditionmonitoringandfaultdetectionforrobotsLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        inNatickMALabel_6               matlab.ui.control.Label
        inNatickMALabel_5               matlab.ui.control.Label
        inNatickMALabel_4               matlab.ui.control.Label
        inNatickMALabel_3               matlab.ui.control.Label
        inNatickMALabel_2               matlab.ui.control.Label
        StopconditionmonitoringButton   matlab.ui.control.Button
        StartconfitionmonitoringButton  matlab.ui.control.Button
        inNatickMALabel                 matlab.ui.control.Label
        UIAxes_pos_6                    matlab.ui.control.UIAxes
        UIAxes_temp_6                   matlab.ui.control.UIAxes
        UIAxes_vol_6                    matlab.ui.control.UIAxes
        UIAxes_pos_5                    matlab.ui.control.UIAxes
        UIAxes_temp_5                   matlab.ui.control.UIAxes
        UIAxes_vol_5                    matlab.ui.control.UIAxes
        UIAxes_pos_4                    matlab.ui.control.UIAxes
        UIAxes_temp_4                   matlab.ui.control.UIAxes
        UIAxes_vol_4                    matlab.ui.control.UIAxes
        UIAxes_pos_3                    matlab.ui.control.UIAxes
        UIAxes_temp_3                   matlab.ui.control.UIAxes
        UIAxes_vol_3                    matlab.ui.control.UIAxes
        UIAxes_pos_2                    matlab.ui.control.UIAxes
        UIAxes_temp_2                   matlab.ui.control.UIAxes
        UIAxes_vol_2                    matlab.ui.control.UIAxes
        UIAxes_vol_1                    matlab.ui.control.UIAxes
        UIAxes_temp_1                   matlab.ui.control.UIAxes
        UIAxes_pos_1                    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        % In this section, we define the properties of the application
        % class. You can access the values of these properties using
        % app.XXX.
        sub = [] % Subsriber object.
        data_limit = 1000
        time_array; % An array of monitoring time.
        idx = 0; % Current index.
        data_motor; % Data matrix for motor 1.
        idx_failure_start = 0; % The index where the state of failure injection changes.
        is_failure; % If a data point relates to failure. For all the motors.
        fail_motor_id = 0; % Failed motor. Initial value is zero.
    end

    methods (Access = private)
    % This section defines the methods of the class application.
       
    function func_receive_msg(app, ~, msg)
    % This is the callback function for the subscriber.
    % Will be executed whenever a msg is published.
    % The received msg will be automatically given to this func as the
    % input "msg".
        % Update the current index.
        app.idx = app.idx + 1;

        % If the maximal data storage is reached
        if app.idx > app.data_limit
            % Close the ROS connection
            rosshutdown;
            
            %% This section save the data and export to .csv file.
            for motor_id = 1:6
                file_name = ['data_motor_' num2str(motor_id) '.csv'];
                % Extract a time array from the 'app' object from the 1st element to the 'idx' element.
                time = app.time_array(1:app.data_limit)';            
                % Extract data related to 'motor_1' from the 'app' object up to the 'idx' element.
                tmp_data = app.data_motor(1:app.data_limit, :, motor_id);            
                % Extract the 'position,' 'temperature,' and 'voltage' columns from 'tmp_data.'
                position = tmp_data(:, 1);
                temperature = tmp_data(:, 2);
                voltage = tmp_data(:, 3);
                label = app.is_failure(1:app.data_limit, motor_id);
                % Create a table from the extracted data columns: time, position, temperature, voltage.
                table_motor_1 = table(time, position, temperature, voltage, label);      
                % Write the table to a CSV file named 'data_motor_1.csv'.
                writetable(table_motor_1, file_name);
            end
        end

        % Get current time
        tnow = datetime("now");
        % Save the data collection time.
        app.time_array{app.idx} = tnow;

        % Parse the data to get a matrix of monitoring data.
        tmp_data = parse_data(app, msg);          
        % Save the CM data.
        app.data_motor(app.idx, :, :) = tmp_data;

        % Update the plots.
        % Hanldes of the Figures.
        plt_handles = {{app.UIAxes_pos_1, app.UIAxes_temp_1, app.UIAxes_vol_1}, ...
            {app.UIAxes_pos_2, app.UIAxes_temp_2, app.UIAxes_vol_2}, ...
            {app.UIAxes_pos_3, app.UIAxes_temp_3, app.UIAxes_vol_3}, ...
            {app.UIAxes_pos_4, app.UIAxes_temp_4, app.UIAxes_vol_4}, ...
            {app.UIAxes_pos_5, app.UIAxes_temp_5, app.UIAxes_vol_5}, ...
            {app.UIAxes_pos_6, app.UIAxes_temp_6, app.UIAxes_vol_6}};
        for i = 1:6
            saveAndPlotMotorData(app, i, plt_handles{i});
        end
   end

       function saveAndPlotMotorData(app, motor_id, plt_handles)                   
            % Get tnow.
            tnow = app.idx;
            % Loop for position, temperature and voltage.
            for i = 1:3
                % Read the current measurement.
                y = app.data_motor(app.idx, i, motor_id);
                % Get the handle to the plot.
                plt = plt_handles{i};

                % Get the current x-axis limits of the app's UIAxes_pos.
                xLL = plt.XLim(1);
                xUL = plt.XLim(2);
    
                % Check if the current time (tnow) is out of bounds.
                if (tnow > xUL)
                    % If it's out of bounds, add 15 seconds to the x-axis limits.
                    xtra = 50;
                    plt.XLim = [xLL + xtra, tnow + xtra];
                    % Adjust the x-axis ticks to maintain readability.
                    plt.XTick = linspace(xLL + xtra, tnow + xtra, 5);
                end
    
                % Plot the position data at the current time tnow.
                plot(plt, tnow, y, ".b");
    
                % Adjust the y-axis limits to show variations better.
                if app.idx <= 2
                    yLL = max(y*.8, 0);
                    yUL = y*1.2;
                else
                    y_lim = plt.YLim;
                    yLL = y_lim(1);
                    yUL = y_lim(2);
                    if y < yLL
                        yLL = .95*y;
                    else if y > yUL
                            yUL = 1.05*y;
                    end
                    end
                end
                plt.YLim = [yLL, yUL];
                plt.YTick = round(linspace(yLL, yUL, 5), 2, "significant");
            end            
        end


       % This function parse the message to get a data matrix.
       % Format of the output: 3*6 matrix.
       % The rows: Position, Temperature, Voltage. The cols: Motors 1-6.
        function data = parse_data(~, msg)
            % Retrieve info from the message
            % Split the string into lines
            data_lines = strsplit(msg.Data, '\n');
            
            % Initialize arrays to store the data
            position_data = [];
            temperature_data = [];
            voltage_data = [];
            
            % Iterate through each line and extract the values
            for i = 1:length(data_lines)
                line = data_lines{i};
                % Split each line by ':' to separate the key and values
                parts = strsplit(line, ':');
                if length(parts) == 2
                    key = strtrim(parts{1});
                    values = str2num(strtrim(strrep(strrep(parts{2}, '[', ''), ']',''))); %# Remove '[' and convert to numeric array
            
                    % Check which key corresponds to the data and store it in the respective array
                    if strcmp(key, 'position')
                        position_data = values;
                    elseif strcmp(key, 'temperature')
                        temperature_data = values;
                    elseif strcmp(key, 'voltage')
                        voltage_data = values;
                    end
                end
            end
            
            data = [position_data; temperature_data; voltage_data];
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Initial values.
            app.time_array = cell(app.data_limit); % An array of monitoring time.
            app.data_motor = zeros(app.data_limit, 3, 6); % Data matrix for motor 1.
            app.is_failure = zeros(app.data_limit, 6); % If a data point relates to failure. For all the motors.

            % Initialize the plots.
            plt_handles = {{app.UIAxes_pos_1, app.UIAxes_temp_1, app.UIAxes_vol_1}, ...
                {app.UIAxes_pos_2, app.UIAxes_temp_2, app.UIAxes_vol_2}, ...
                {app.UIAxes_pos_3, app.UIAxes_temp_3, app.UIAxes_vol_3}, ...
                {app.UIAxes_pos_4, app.UIAxes_temp_4, app.UIAxes_vol_4}, ...
                {app.UIAxes_pos_5, app.UIAxes_temp_5, app.UIAxes_vol_5}, ...
                {app.UIAxes_pos_6, app.UIAxes_temp_6, app.UIAxes_vol_6}};

            for j = 1:6
                tmp_hdl = plt_handles{j};
                for i = 1:3                    
                    plt = tmp_hdl{i};
                    % Plot invisible data point to initialize datetime ruler
                    plot(plt, 500, 50, "MarkerEdgeColor", "none");           
                    % Set x limits and ticks
                    xLL = 0;
                    xUL = 1000;
                    plt.XLim = [xLL xUL];
                    plt.XTick = linspace(xLL,xUL,5);
                    
                    % Set y limits and ticks
                    %plt.YLim = [0 500];
                    %plt.YTick = round(linspace(0,500,5),2,"significant");
                    grid(plt,"on");
                    hold(plt,"on");
                end
            end
        end

        % Button pushed function: StartconfitionmonitoringButton
        function StartconfitionmonitoringButtonPushed(app, event)
           % Shutdown ros nodes, if any.
           rosshutdown;               
           % Connect to the robot: The ip address is 192.168.0.103.
           % rosinit('192.168.0.103', 11311)  
           rosinit('192.168.1.57', 11311)

           % Set this ip address to be the fake robot's, if you are
           % using the offline testing code.
           % rosinit('192.168.1.14', 11311)
           % rosinit('138.195.233.201', 11311)

           % Create a subscriber to the 'condition_monitoring' topic
           my_callback = @(src, msg)func_receive_msg(app, src, msg);
           app.sub = rossubscriber('/condition_monitoring', my_callback);            
        end

        % Button pushed function: StopconditionmonitoringButton
        function StopconditionmonitoringButtonPushed(app, event)
            % Close the ROS connection
            rosshutdown;
            
            %% This section save the data and export to .csv file.
            for motor_id = 1:6
                file_name = ['data_motor_' num2str(motor_id) '.csv'];
                % Extract a time array from the 'app' object from the 1st element to the 'idx' element.
                time = app.time_array(1:app.idx)';            
                % Extract data related to 'motor_1' from the 'app' object up to the 'idx' element.
                tmp_data = app.data_motor(1:app.idx, :, motor_id);            
                % Extract the 'position,' 'temperature,' and 'voltage' columns from 'tmp_data.'
                position = tmp_data(:, 1);
                temperature = tmp_data(:, 2);
                voltage = tmp_data(:, 3);
                label = app.is_failure(1:app.idx, motor_id);
                % Create a table from the extracted data columns: time, position, temperature, voltage.
                table_motor_1 = table(time, position, temperature, voltage, label);      
                % Write the table to a CSV file named 'data_motor_1.csv'.
                writetable(table_motor_1, file_name);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Close the ROS connection
            rosshutdown;
            delete(app);
        end

        % Value changed function: FailureinjectionSwitch
        function FailureinjectionSwitchValueChanged(app, event)
            % Get the value of the button.
            value = app.FailureinjectionSwitch.Value;
            % disp(value)
            if strcmp(value, 'Off') % If we stop the failure injection
                % We set the value of is_failure
                if app.idx_failure_start > 0
                    app.is_failure(app.idx_failure_start:app.idx, app.fail_motor_id) = 1; % From the starting moment to the current moment.
                end
            else % If we begin inject failure
                % Get the change point.
                app.idx_failure_start = app.idx;
                app.fail_motor_id = str2double(app.InjectfailureonDropDown.Value);
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Demo_Condition monitoring';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes_pos_1 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_pos_1, 'Measurement index')
            ylabel(app.UIAxes_pos_1, 'position')
            app.UIAxes_temp_1 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_temp_1, 'Measurement index')
            ylabel(app.UIAxes_temp_1, 'temperature')
            app.UIAxes_vol_1 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_vol_1, 'Measurement index')
            ylabel(app.UIAxes_vol_1, 'voltage')
            app.UIAxes_vol_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_vol_2, 'Measurement index')
            ylabel(app.UIAxes_vol_2, 'voltage')
            app.UIAxes_temp_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_temp_2, 'Measurement index')
            ylabel(app.UIAxes_temp_2, 'temperature')
            app.UIAxes_pos_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_pos_2, 'Measurement index')
            ylabel(app.UIAxes_pos_2, 'position')
            app.UIAxes_vol_3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_vol_3, 'Measurement index')
            ylabel(app.UIAxes_vol_3, 'voltage')
            app.UIAxes_temp_3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_temp_3, 'Measurement index')
            ylabel(app.UIAxes_temp_3, 'temperature')
            app.UIAxes_pos_3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_pos_3, 'Measurement index')
            ylabel(app.UIAxes_pos_3, 'position')
            app.UIAxes_vol_4 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_vol_4, 'Measurement index')
            ylabel(app.UIAxes_vol_4, 'voltage')
            app.UIAxes_temp_4 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_temp_4, 'Measurement index')
            ylabel(app.UIAxes_temp_4, 'temperature')
            app.UIAxes_pos_4 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_pos_4, 'Measurement index')
            ylabel(app.UIAxes_pos_4, 'position')
            app.UIAxes_vol_5 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_vol_5, 'Measurement index')
            ylabel(app.UIAxes_vol_5, 'voltage')
            app.UIAxes_temp_5 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_temp_5, 'Measurement index')
            ylabel(app.UIAxes_temp_5, 'temperature')
            app.UIAxes_pos_5 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_pos_5, 'Measurement index')
            ylabel(app.UIAxes_pos_5, 'position')
            app.UIAxes_vol_6 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_vol_6, 'Measurement index')
            ylabel(app.UIAxes_vol_6, 'voltage')
            app.UIAxes_temp_6 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_temp_6, 'Measurement index')
            ylabel(app.UIAxes_temp_6, 'temperature')
            app.UIAxes_pos_6 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_pos_6, 'Measurement index')
            ylabel(app.UIAxes_pos_6, 'position')
            app.inNatickMALabel = uilabel(app.UIFigure);
            app.inNatickMALabel.Text = 'Motor 1';
            app.StartconfitionmonitoringButton = uibutton(app.UIFigure, 'push');
            app.StartconfitionmonitoringButton.ButtonPushedFcn = createCallbackFcn(app, @StartconfitionmonitoringButtonPushed, true);
            app.StartconfitionmonitoringButton.Text = 'Start confition-monitoring';
            app.StopconditionmonitoringButton = uibutton(app.UIFigure, 'push');
            app.StopconditionmonitoringButton.ButtonPushedFcn = createCallbackFcn(app, @StopconditionmonitoringButtonPushed, true);
            app.StopconditionmonitoringButton.Text = 'Stop condition-monitoring';
            app.inNatickMALabel_2 = uilabel(app.UIFigure);
            app.inNatickMALabel_2.Text = 'Motor 2';
            app.inNatickMALabel_3 = uilabel(app.UIFigure);
            app.inNatickMALabel_3.Text = 'Motor 3';
            app.inNatickMALabel_4 = uilabel(app.UIFigure);
            app.inNatickMALabel_4.Text = 'Motor 4';
            app.inNatickMALabel_5 = uilabel(app.UIFigure);
            app.inNatickMALabel_5.Text = 'Motor 5';
            app.inNatickMALabel_6 = uilabel(app.UIFigure);
            app.inNatickMALabel_6.Text = 'Motor 6';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'robot_picture.png');
            app.ConditionmonitoringandfaultdetectionforrobotsLabel = uilabel(app.UIFigure);
            app.ConditionmonitoringandfaultdetectionforrobotsLabel.Text = 'Condition-monitoring and fault detection for robots';
            app.InjectfailureonDropDownLabel = uilabel(app.UIFigure);
            app.InjectfailureonDropDownLabel.Text = 'Inject failure on';
            app.InjectfailureonDropDown = uidropdown(app.UIFigure);
            app.InjectfailureonDropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.InjectfailureonDropDown.Value = '1';
            app.FailureinjectionSwitchLabel = uilabel(app.UIFigure);
            app.FailureinjectionSwitchLabel.Text = 'Failure injection';
            app.FailureinjectionSwitch = uiswitch(app.UIFigure, 'slider');
            app.FailureinjectionSwitch.ValueChangedFcn = createCallbackFcn(app, @FailureinjectionSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_prototype_fault_detection_master

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end