classdef app_prototype_fault_detection_client < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        PleasechoosetheoperationmodeSwitch  matlab.ui.control.Switch
        PleasechoosetheoperationmodeSwitchLabel  matlab.ui.control.Label
        ConditionmonitoringandfaultdetectionforrobotsLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        inNatickMALabel_6               matlab.ui.control.Label
        inNatickMALabel_5               matlab.ui.control.Label
        inNatickMALabel_4               matlab.ui.control.Label
        inNatickMALabel_3               matlab.ui.control.Label
        inNatickMALabel_2               matlab.ui.control.Label
        StopconditionmonitoringButton   matlab.ui.control.Button
        StartconfitionmonitoringButton  matlab.ui.control.Button
        inNatickMALabel                 matlab.ui.control.Label
        UIAxes_pos_6                    matlab.ui.control.UIAxes
        UIAxes_temp_6                   matlab.ui.control.UIAxes
        UIAxes_vol_6                    matlab.ui.control.UIAxes
        UIAxes_pos_5                    matlab.ui.control.UIAxes
        UIAxes_temp_5                   matlab.ui.control.UIAxes
        UIAxes_vol_5                    matlab.ui.control.UIAxes
        UIAxes_pos_4                    matlab.ui.control.UIAxes
        UIAxes_temp_4                   matlab.ui.control.UIAxes
        UIAxes_vol_4                    matlab.ui.control.UIAxes
        UIAxes_pos_3                    matlab.ui.control.UIAxes
        UIAxes_temp_3                   matlab.ui.control.UIAxes
        UIAxes_vol_3                    matlab.ui.control.UIAxes
        UIAxes_pos_2                    matlab.ui.control.UIAxes
        UIAxes_temp_2                   matlab.ui.control.UIAxes
        UIAxes_vol_2                    matlab.ui.control.UIAxes
        UIAxes_vol_1                    matlab.ui.control.UIAxes
        UIAxes_temp_1                   matlab.ui.control.UIAxes
        UIAxes_pos_1                    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        % In this section, we define the properties of the application
        % class. You can access the values of these properties using
        % app.XXX.

        sub = []; % Subsriber object.
        data_limit = 5000; % Maximum number of data points to be collected.
        time_array; % An array of monitoring time.
        idx = 0; % Current index.
        data_motor; % Data matrix for the condition-monitoring data.
        is_failure_pred; % If a data point relates to failure. For all the motors.
        plt_handles; % Handes for the plts.
        fault_detection_mdl; % The trained failure detection model. To be loaded from the workspace.
        operation_mode = 'Simulated robot'; % Whether we are using real/fake robot.
        monitoring_starts = 0; % Flag to define if it is the starting moment of a sequence. Only used in 'Simulated robot" mode.
    end

    methods (Access = private)
    % This section defines the methods of the class application.
       
        function func_receive_msg(app, ~, msg)
        % This is the callback function for the subscriber.
        % Will be executed whenever a msg is published.
        % The received msg will be automatically given to this func as the
        % input "msg".

            % Parse the data to get a matrix of monitoring data.
            [tmp_data, is_stop] = parse_data(app, msg);

            % is_stop is a flag variable indicating that if the monintoring
            % should be stopped because the data sequence sent by the robot
            % is finished (when used the 'Simulated robot' mode).
            if is_stop == 0
                app.idx = app.idx + 1;
                % Get current time
                tnow = datetime("now", 'Format', 'yyyy-MM-dd HH:mm:ss.SSS');
                % Save the data collection time.
                app.time_array{app.idx} = tnow; 
            
                % Save the CM data.
                app.data_motor(app.idx, :, :) = tmp_data;
        
                % Do fault detection and update the plots.        
                for motor_id = 1:6
                    saveAndPlotMotorData(app, motor_id, app.plt_handles{motor_id});
                end     
            end
       end

       function saveAndPlotMotorData(app, motor_id, plt_handles)                   
       % This function detects if a failure occurs at the current time, and
       % update the plots.

            % Get tnow.
            tnow = app.idx;

            % Make failure detection.
            % Prepare the feature.
            feature = app.data_motor(tnow, :, :);
            feature = feature(:);
            % feature = pyrun('tmp = np.array(feature).reshape(1, -1)', 'tmp', feature=feature);
            % Run fault detection by calling the fault detection model,
            % trained in Python.
            pred_state = app.fault_detection_mdl.predict(feature);
            
            % Save the prediction and visualize the results.
            if pred_state == 0
                marker = '.b'; % Blue dot for normal samples
            else
                marker = 'xr'; % Red cross for failures
                app.is_failure_pred(app.idx, motor_id) = 1;
            end
            % Loop for position, temperature and voltage.
            for i = 1:3
                % Read the current measurement.
                y = app.data_motor(app.idx, i, motor_id);
                % Get the handle to the plot.
                plt = plt_handles{i};

                % Get the current x-axis limits of the app's UIAxes_pos.
                xLL = plt.XLim(1);
                xUL = plt.XLim(2);
    
                % Check if the current time (tnow) is out of bounds.
                if (tnow > xUL)
                    % If it's out of bounds, add 15 seconds to the x-axis limits.
                    xtra = 50;
                    plt.XLim = [xLL + xtra, tnow + xtra];
                    % Adjust the x-axis ticks to maintain readability.
                    plt.XTick = linspace(xLL + xtra, tnow + xtra, 5);
                end
    
                % Plot the position data at the current time tnow.
                plot(plt, tnow, y, marker);
    
                % Adjust the y-axis limits to show variations better.
                if app.idx <= 2
                    yLL = max(y*.8, 0);
                    yUL = y*1.2;
                else
                    y_lim = plt.YLim;
                    yLL = y_lim(1);
                    yUL = y_lim(2);
                    if y < yLL
                        yLL = .8*y;
                    else 
                        if y > yUL
                            yUL = 1.2*y;
                        end
                    end
                end
                plt.YLim = [yLL, yUL];
                plt.YTick = round(linspace(yLL, yUL, 5), 2, "significant");
            end            
       end


       % This function parse the message to get a data matrix.
       % Format of the output: 3*6 matrix.
       % The rows: Position, Temperature, Voltage. The cols: Motors 1-6.
        function [data, is_stop] = parse_data(app, msg)
            is_stop = 0; % Flag: If 1: End the program.
            % Get the operation mode:
            switch app.operation_mode
                % If we are using the 'simulated robot' mode:
                % First check if it is a beginning of a sequence.
                % If yes, perform the fault detection program.
                % If no, wait until the sequence starts from the beginning
                % agina.
                case 'Simulated robot'
                    if strcmp(msg.Data, 'Prepare to receive the data!')
                        % If the monitoring is going on, stop it.
                        if app.monitoring_starts == 1
                            app.monitoring_starts = 0;
                            % Stop the condition monitoring.
                            app.stop_monitoring()
                        else % If the monitoring does not start, start it.
                            app.monitoring_starts = 1;
                            app.idx = 0;
                            app.init_parameters();
                        end
                        
                        data = zeros(3, 6);
                        is_stop = 1;
                    else
                        data = parse_data_base(app, msg);
                    end

                % If we are using 'Real robot' mode, parce the data
                % directly.
                case 'Real robot'
                    data = parse_data_base(app, msg);

                otherwise
                    error('Operation mode undefined!')
            end
        
        end

       % This function parse the message to get a data matrix.
       % Format of the output: 3*6 matrix.
       % The rows: Position, Temperature, Voltage. The cols: Motors 1-6.
       function data = parse_data_base(~, msg)
            % Retrieve info from the message
            % Split the string into lines
            data_lines = strsplit(msg.Data, '\n');
            
            % Initialize arrays to store the data
            position_data = [];
            temperature_data = [];
            voltage_data = [];
            
            % Iterate through each line and extract the values
            for i = 1:length(data_lines)
                line = data_lines{i};
                % Split each line by ':' to separate the key and values
                parts = strsplit(line, ':');
                if length(parts) == 2
                    key = strtrim(parts{1});
                    values = str2num(strtrim(strrep(strrep(parts{2}, '[', ''), ']',''))); %# Remove '[' and convert to numeric array
            
                    % Check which key corresponds to the data and store it in the respective array
                    if strcmp(key, 'position')
                        position_data = values;
                    elseif strcmp(key, 'temperature')
                        temperature_data = values;
                    elseif strcmp(key, 'voltage')
                        voltage_data = values;
                    end
                end
            end
            
            data = [position_data; temperature_data; voltage_data];
       end

       function stop_monitoring(app)
           % Close the ROS connection
            rosshutdown;
            
            % This section save the data and export to .csv file.
            for motor_id = 1:6
                file_name = ['prediction_results_motor_' num2str(motor_id) '.csv'];
                % Extract a time array from the 'app' object from the 1st element to the 'idx' element.
                time = app.time_array(1:app.idx)';            
                % Extract data related to 'motor_1' from the 'app' object up to the 'idx' element.
                tmp_data = app.data_motor(1:app.idx, :, motor_id);            
                % Extract the 'position,' 'temperature,' and 'voltage' columns from 'tmp_data.'
                position = tmp_data(:, 1);
                temperature = tmp_data(:, 2);
                voltage = tmp_data(:, 3);
                pred_label = app.is_failure_pred(1:app.idx, motor_id);
                % Create a table from the extracted data columns: time, position, temperature, voltage.
                table_motor_1 = table(time, position, temperature, voltage, pred_label);      
                % Write the table to a CSV file named 'data_motor_1.csv'.
                writetable(table_motor_1, file_name);
            end
       end

       function init_parameters(app)
            % Initial values.
            app.time_array = cell(app.data_limit); % An array of monitoring time.
            app.data_motor = zeros(app.data_limit, 3, 6); % Data matrix for motor 1.
            app.is_failure_pred = zeros(app.data_limit, 6); % If a data point relates to failure. For all the motors.
            
            app.plt_handles = {{app.UIAxes_pos_1, app.UIAxes_temp_1, app.UIAxes_vol_1}, ...
            {app.UIAxes_pos_2, app.UIAxes_temp_2, app.UIAxes_vol_2}, ...
            {app.UIAxes_pos_3, app.UIAxes_temp_3, app.UIAxes_vol_3}, ...
            {app.UIAxes_pos_4, app.UIAxes_temp_4, app.UIAxes_vol_4}, ...
            {app.UIAxes_pos_5, app.UIAxes_temp_5, app.UIAxes_vol_5}, ...
            {app.UIAxes_pos_6, app.UIAxes_temp_6, app.UIAxes_vol_6}};
                     
            for j = 1:6
                tmp_hdl = app.plt_handles{j};
                for i = 1:3                    
                    plt = tmp_hdl{i};
                    cla(plt);
                    % Plot invisible data point to initialize datetime ruler
                    plot(plt, 500, 50, "MarkerEdgeColor", "none");           
                    % Set x limits and ticks
                    xLL = 0;
                    xUL = 1000;
                    plt.XLim = [xLL xUL];
                    plt.XTick = linspace(xLL,xUL,5);
                    
                    % Set y limits and ticks
                    %plt.YLim = [0 500];
                    %plt.YTick = round(linspace(0,500,5),2,"significant");
                    grid(plt,"on");
                    hold(plt,"on");
                end
            end
       end

    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc; % Clear outputs in the screen.
            app.init_parameters() % Initialize the parameters
            
            % Load the failure detection model in MATLAB
            try 
                pyrun('import numpy as np')
                app.fault_detection_mdl = pyrunfile('load_model.py', 'clf');
            catch error
                error('Failed to load the fault detection model!')                
            end
            % If successful, log.
            fprintf('[INFO] - Successfully loaded the fault detection model.\n')
        end

        % Button pushed function: StartconfitionmonitoringButton
        function StartconfitionmonitoringButtonPushed(app, event)
           % Shutdown ros nodes, if any.
           rosshutdown;               
           % Connect to the robot: The ip address is 192.168.0.103.
           % rosinit('192.168.0.103', 11311)                

           % Set this ip address to be the fake robot's, if you are
           % using the offline testing code.
           rosinit('192.168.1.14', 11311)
           % rosinit('138.195.233.201', 11311)

           % Create a subscriber to the 'condition_monitoring' topic
           my_callback = @(src, msg)func_receive_msg(app, src, msg);
           app.sub = rossubscriber('/condition_monitoring', my_callback);            
        end

        % Button pushed function: StopconditionmonitoringButton
        function StopconditionmonitoringButtonPushed(app, event)
            app.stop_monitoring();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Close the ROS connection
            rosshutdown;
            delete(app);
        end

        % Value changed function: PleasechoosetheoperationmodeSwitch
        function PleasechoosetheoperationmodeSwitchValueChanged(app, event)
            value = app.PleasechoosetheoperationmodeSwitch.Value;
            if strcmp(value, 'Simulated robot') || strcmp(value, 'Real robot')
                app.operation_mode = value;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Demo_Condition monitoring';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes_pos_1 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_pos_1, 'Measurement index')
            ylabel(app.UIAxes_pos_1, 'position')
            app.UIAxes_temp_1 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_temp_1, 'Measurement index')
            ylabel(app.UIAxes_temp_1, 'temperature')
            app.UIAxes_vol_1 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_vol_1, 'Measurement index')
            ylabel(app.UIAxes_vol_1, 'voltage')
            app.UIAxes_vol_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_vol_2, 'Measurement index')
            ylabel(app.UIAxes_vol_2, 'voltage')
            app.UIAxes_temp_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_temp_2, 'Measurement index')
            ylabel(app.UIAxes_temp_2, 'temperature')
            app.UIAxes_pos_2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_pos_2, 'Measurement index')
            ylabel(app.UIAxes_pos_2, 'position')
            app.UIAxes_vol_3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_vol_3, 'Measurement index')
            ylabel(app.UIAxes_vol_3, 'voltage')
            app.UIAxes_temp_3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_temp_3, 'Measurement index')
            ylabel(app.UIAxes_temp_3, 'temperature')
            app.UIAxes_pos_3 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_pos_3, 'Measurement index')
            ylabel(app.UIAxes_pos_3, 'position')
            app.UIAxes_vol_4 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_vol_4, 'Measurement index')
            ylabel(app.UIAxes_vol_4, 'voltage')
            app.UIAxes_temp_4 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_temp_4, 'Measurement index')
            ylabel(app.UIAxes_temp_4, 'temperature')
            app.UIAxes_pos_4 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_pos_4, 'Measurement index')
            ylabel(app.UIAxes_pos_4, 'position')
            app.UIAxes_vol_5 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_vol_5, 'Measurement index')
            ylabel(app.UIAxes_vol_5, 'voltage')
            app.UIAxes_temp_5 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_temp_5, 'Measurement index')
            ylabel(app.UIAxes_temp_5, 'temperature')
            app.UIAxes_pos_5 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_pos_5, 'Measurement index')
            ylabel(app.UIAxes_pos_5, 'position')
            app.UIAxes_vol_6 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_vol_6, 'Measurement index')
            ylabel(app.UIAxes_vol_6, 'voltage')
            app.UIAxes_temp_6 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_temp_6, 'Measurement index')
            ylabel(app.UIAxes_temp_6, 'temperature')
            app.UIAxes_pos_6 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_pos_6, 'Measurement index')
            ylabel(app.UIAxes_pos_6, 'position')
            app.inNatickMALabel = uilabel(app.UIFigure);
            app.inNatickMALabel.Text = 'Motor 1';
            app.StartconfitionmonitoringButton = uibutton(app.UIFigure, 'push');
            app.StartconfitionmonitoringButton.ButtonPushedFcn = createCallbackFcn(app, @StartconfitionmonitoringButtonPushed, true);
            app.StartconfitionmonitoringButton.Text = 'Start confition-monitoring';
            app.StopconditionmonitoringButton = uibutton(app.UIFigure, 'push');
            app.StopconditionmonitoringButton.ButtonPushedFcn = createCallbackFcn(app, @StopconditionmonitoringButtonPushed, true);
            app.StopconditionmonitoringButton.Text = 'Stop condition-monitoring';
            app.inNatickMALabel_2 = uilabel(app.UIFigure);
            app.inNatickMALabel_2.Text = 'Motor 2';
            app.inNatickMALabel_3 = uilabel(app.UIFigure);
            app.inNatickMALabel_3.Text = 'Motor 3';
            app.inNatickMALabel_4 = uilabel(app.UIFigure);
            app.inNatickMALabel_4.Text = 'Motor 4';
            app.inNatickMALabel_5 = uilabel(app.UIFigure);
            app.inNatickMALabel_5.Text = 'Motor 5';
            app.inNatickMALabel_6 = uilabel(app.UIFigure);
            app.inNatickMALabel_6.Text = 'Motor 6';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'robot_picture.png');
            app.ConditionmonitoringandfaultdetectionforrobotsLabel = uilabel(app.UIFigure);
            app.ConditionmonitoringandfaultdetectionforrobotsLabel.Text = 'Condition-monitoring and fault detection for robots';
            app.PleasechoosetheoperationmodeSwitchLabel = uilabel(app.UIFigure);
            app.PleasechoosetheoperationmodeSwitchLabel.Text = 'Please choose the operation mode';
            app.PleasechoosetheoperationmodeSwitch = uiswitch(app.UIFigure, 'slider');
            app.PleasechoosetheoperationmodeSwitch.Items = {'Simulated robot', 'Real robot'};
            app.PleasechoosetheoperationmodeSwitch.ValueChangedFcn = createCallbackFcn(app, @PleasechoosetheoperationmodeSwitchValueChanged, true);
            app.PleasechoosetheoperationmodeSwitch.Value = 'Simulated robot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_prototype_fault_detection_client

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end