classdef app_prototype_data_collection < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        FailureinjectionSwitch        matlab.ui.control.Switch
        FailureinjectionSwitchLabel   matlab.ui.control.Label
        InjectfailureonDropDown       matlab.ui.control.DropDown
        InjectfailureonDropDownLabel  matlab.ui.control.Label
        StopButton                    matlab.ui.control.Button
        StartButton                   matlab.ui.control.Button
        inNatickMALabel               matlab.ui.control.Label
        UIAxes_vol                    matlab.ui.control.UIAxes
        UIAxes_temp                   matlab.ui.control.UIAxes
        UIAxes_pos                    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        % In this section, we define the properties of the application
        % class. You can access the values of these properties using
        % app.XXX.
        sub = [] % Subsriber object.
        time_array = cell(1000); % An array of monitoring time.       
        data_motor = zeros(1000, 3, 6); % Data matrix for motor 1.
        idx = 0; % Current index.
        idx_failure_start = 0; % The index where the state of failure injection changes.
        is_failure = zeros(1000, 6); % If a data point relates to failure. For all the motors.
        fail_motor_id = 0; % Failed motor. Initial value is zero.
    end

    methods (Access = private)
    % This section defines the methods of the class application.
       
    function func_receive_msg(app, ~, msg)
    % This is the callback function for the subscriber.
    % Will be executed whenever a msg is published.
    % The received msg will be automatically given to this func as the
    % input "msg".
        % Update the current index.
        app.idx = app.idx + 1;
        % Get current time
        tnow = datetime("now");
        % Save the data collection time.
        app.time_array{app.idx} = tnow;

        % Parse the data to get a matrix of monitoring data.
        tmp_data = parse_data(app, msg);          
        % For motor 1:
        motor_id = 1;
        % Save the CM data.
        app.data_motor(app.idx, :, motor_id) = tmp_data(:, motor_id);
        saveAndPlotMotorData(app, motor_id);
   end


   function saveAndPlotMotorData(app, motor_id)                   
        % Get tnow.
        tnow = app.time_array{app.idx};
        % Hanldes of the Figures.
        plt_handles = {app.UIAxes_pos, app.UIAxes_temp, app.UIAxes_vol};
        % Loop for position, temperature and voltage.
        for i = 1:3
            % Read the current measurement.
            y = app.data_motor(app.idx, i, motor_id);
            % Get the handle to the plot.
            plt = plt_handles{i};

            % Get the current x-axis limits of the app's UIAxes_pos.
            xLL = plt.XLim(1);
            xUL = plt.XLim(2);

            % Check if the current time (tnow) is out of bounds.
            if (tnow > xUL)
                % If it's out of bounds, add 15 seconds to the x-axis limits.
                xtra = seconds(5);
                plt.XLim = [xLL + xtra, tnow + xtra];
                % Adjust the x-axis ticks to maintain readability.
                plt.XTick = linspace(xLL + xtra, tnow + xtra, 5);
            end

            % Plot the position data at the current time tnow.
            plot(plt, tnow, y, ".b");

            % Adjust the y-axis limits to show variations better.
            if app.idx <= 2
                yLL = max(y*.8, 0);
                yUL = y*1.2;
            else
                y_lim = plt.YLim;
                yLL = y_lim(1);
                yUL = y_lim(2);
                if y < yLL
                    yLL = .95*y;
                else if y > yUL
                        yUL = 1.05*y;
                end
                end
            end
            plt.YLim = [yLL, yUL];
            plt.YTick = round(linspace(yLL, yUL, 5), 2, "significant");
        end            
    end


   % This function parse the message to get a data matrix.
   % Format of the output: 3*6 matrix.
   % The rows: Position, Temperature, Voltage. The cols: Motors 1-6.
    function data = parse_data(~, msg)
        % Retrieve info from the message
        % Split the string into lines
        data_lines = strsplit(msg.Data, '\n');
        
        % Initialize arrays to store the data
        position_data = [];
        temperature_data = [];
        voltage_data = [];
        
        % Iterate through each line and extract the values
        for i = 1:length(data_lines)
            line = data_lines{i};
            % Split each line by ':' to separate the key and values
            parts = strsplit(line, ':');
            if length(parts) == 2
                key = strtrim(parts{1});
                values = str2num(strtrim(strrep(strrep(parts{2}, '[', ''), ']',''))); %# Remove '[' and convert to numeric array
        
                % Check which key corresponds to the data and store it in the respective array
                if strcmp(key, 'position')
                    position_data = values;
                elseif strcmp(key, 'temperature')
                    temperature_data = values;
                elseif strcmp(key, 'voltage')
                    voltage_data = values;
                end
            end
        end
        
        data = [position_data; temperature_data; voltage_data];
    end
    
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            plt_handles = {app.UIAxes_pos, app.UIAxes_temp, app.UIAxes_vol};

            for i = 1:3
                plt = plt_handles{i};
                % Plot invisible data point to initialize datetime ruler
                plot(plt, datetime("now","Format","hh:mm:ss"),50,"MarkerEdgeColor","none");           
                % Set x limits and ticks
                t = datetime("now","Format","hh:mm:ss") + seconds(1:60);
                xLL = t(1) - seconds(5);
                xUL = t(length(t));
                plt.XLim = [xLL xUL];
                plt.XAxis.TickLabelFormat = 'hh:mm:ss';
                plt.XTick = linspace(xLL,xUL,5);
                
                % Set y limits and ticks
                %plt.YLim = [0 500];
                %plt.YTick = round(linspace(0,500,5),2,"significant");
                grid(plt,"on");
                hold(plt,"on");
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
           % Shutdown ros nodes, if any.
           rosshutdown;               
           % % Connect to the robot: The ip address is 192.168.0.103.
           % rosinit('192.168.0.103', 11311)                

           % Set this ip address to be the fake robot's, if you are
           % using the offline testing code.
           rosinit('192.168.1.14', 11311)
           % rosinit('138.195.233.201', 11311)

           % Create a subscriber to the 'condition_monitoring' topic
           my_callback = @(src, msg)func_receive_msg(app, src, msg);
           app.sub = rossubscriber('/condition_monitoring', my_callback);            
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            % Close the ROS connection
            rosshutdown;
            
            %% This section save the data and export to .csv file.
            motor_id = 1;
            file_name = ['data_motor_' num2str(motor_id) '.csv'];
            % Extract a time array from the 'app' object from the 1st element to the 'idx' element.
            time = app.time_array(1:app.idx)';            
            % Extract data related to 'motor_1' from the 'app' object up to the 'idx' element.
            tmp_data = app.data_motor(1:app.idx, :, motor_id);            
            % Extract the 'position,' 'temperature,' and 'voltage' columns from 'tmp_data.'
            position = tmp_data(:, 1);
            temperature = tmp_data(:, 2);
            voltage = tmp_data(:, 3);
            label = app.is_failure(1:app.idx, motor_id);
            % Create a table from the extracted data columns: time, position, temperature, voltage and label.
            table_motor_1 = table(time, position, temperature, voltage, label);            
            % Write the table to a CSV file named 'data_motor_1.csv'.
            writetable(table_motor_1, file_name);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            % Close the ROS connection
            rosshutdown;
            delete(app);
        end

        % Value changed function: FailureinjectionSwitch
        function FailureinjectionSwitchValueChanged(app, event)
            % Get the value of the button.
            value = app.FailureinjectionSwitch.Value;
            disp(value)
            if strcmp(value, 'Off') % If we stop the failure injection
                % We set the value of is_failure
                if app.idx_failure_start > 0
                    app.is_failure(app.idx_failure_start:app.idx, app.fail_motor_id) = 1; % From the starting moment to the current moment.
                end
            else % If we begin inject failure
                % Get the change point.
                app.idx_failure_start = app.idx;
                app.fail_motor_id = str2double(app.InjectfailureonDropDown.Value);
            end 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Demo_Condition monitoring';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes_pos = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_pos, 'Time (Seconds)')
            ylabel(app.UIAxes_pos, 'position')
            app.UIAxes_temp = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_temp, 'Time (Seconds)')
            ylabel(app.UIAxes_temp, 'temperature')
            app.UIAxes_vol = uiaxes(app.UIFigure);
            xlabel(app.UIAxes_vol, 'Time (Seconds)')
            ylabel(app.UIAxes_vol, 'voltage')
            app.inNatickMALabel = uilabel(app.UIFigure);
            app.inNatickMALabel.Text = 'Motor 1';
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.InjectfailureonDropDownLabel = uilabel(app.UIFigure);
            app.InjectfailureonDropDownLabel.Text = 'Inject failure on';
            app.InjectfailureonDropDown = uidropdown(app.UIFigure);
            app.InjectfailureonDropDown.Items = {'1', '2', '3', '4', '5', '6'};
            app.InjectfailureonDropDown.Value = '1';
            app.FailureinjectionSwitchLabel = uilabel(app.UIFigure);
            app.FailureinjectionSwitchLabel.Text = 'Failure injection';
            app.FailureinjectionSwitch = uiswitch(app.UIFigure, 'slider');
            app.FailureinjectionSwitch.ValueChangedFcn = createCallbackFcn(app, @FailureinjectionSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_prototype_data_collection

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end